classdef PlateReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlateReaderUIFigure             matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        LoadlayoutMenu                  matlab.ui.container.Menu
        LoaddataMenu                    matlab.ui.container.Menu
        ExportanalysisMenu              matlab.ui.container.Menu
        LoadanalysisMenu                matlab.ui.container.Menu
        AssayTabs                       matlab.ui.container.TabGroup
        BradfordTab                     matlab.ui.container.Tab
        SampleValuesPanel               matlab.ui.container.Panel
        DilutionfactorEditField         matlab.ui.control.NumericEditField
        DilutionfactorEditFieldLabel    matlab.ui.control.Label
        SampleValuesAxes                matlab.ui.control.UIAxes
        StandardCurvePanel              matlab.ui.container.Panel
        InterceptEditField              matlab.ui.control.NumericEditField
        InterceptEditFieldLabel         matlab.ui.control.Label
        rvalueEditField                 matlab.ui.control.NumericEditField
        rvalueEditFieldLabel            matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        StandardCurveAxes               matlab.ui.control.UIAxes
        CitrateSynthaseTab              matlab.ui.container.Tab
        SampleValuesPanel_2             matlab.ui.container.Panel
        DilutionfactorEditField_2       matlab.ui.control.NumericEditField
        DilutionfactorEditField_2Label  matlab.ui.control.Label
        SampleValuesAxes_2              matlab.ui.control.UIAxes
        StandardCurvePanel_2            matlab.ui.container.Panel
        InterceptEditField_2            matlab.ui.control.NumericEditField
        InterceptEditField_2Label       matlab.ui.control.Label
        rvalueEditField_2               matlab.ui.control.NumericEditField
        rvalueEditField_2Label          matlab.ui.control.Label
        SlopeEditField_2                matlab.ui.control.NumericEditField
        SlopeEditField_2Label           matlab.ui.control.Label
        StandardCurveAxes_2             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        plate_reader_data % Description
    end

    methods (Access = public)

        function CalculateStandardCurve(app)

            col_names = app.plate_reader_data.col_names;
            plate_layout = app.plate_reader_data.plate_layout;
            formatted_data = app.plate_reader_data.formatted_data;
            standard_concentrations = app.plate_reader_data.standard_concentrations.concentration;
            analyzed_data = formatted_data;
            assay = app.AssayTabs.SelectedTab.Title;


            formatted_data_size = size(formatted_data);

            if ndims(formatted_data) > 2
                counter = formatted_data_size(3);
            end


            for i = 1 : numel(col_names)
                standard_mask(:,i) = strcmpi(plate_layout.(col_names{i}),'Standard');
            end
        
            switch assay
                case 'Bradford'
                    standard_values = formatted_data(any(standard_mask,2),any(standard_mask,1));

                    standard_mean = mean(standard_values,2,'omitnan');
                case 'Citrate Synthase'
                    for i = 1 : counter
                        time_point_data = []
                        time_point_data = formatted_data(:,:,i)
                        standard_values = []
                        standard_values = formatted_data(any(standard_mask,2),any(standard_mask,1));

                        standard_mean(:,i) = mean(standard_values,2,'omitnan');
                    end
                    standard_mean = mean(standard_mean,2)
            end


            x = standard_concentrations;
            y = standard_mean;

            line_fit = fit_linear_model(x, y);

            hold(app.StandardCurveAxes,"on")
            plot(app.StandardCurveAxes,line_fit.x_fit, line_fit.y_fit, 'LineStyle','-', ...
                'LineWidth', 1.75,'Color',[0 0 0 0.7]);
            scatter(app.StandardCurveAxes,standard_concentrations,standard_mean,50,'MarkerFaceColor','g','MarkerEdgeColor','k','MarkerFaceAlpha',0.5)
            xlim(app.StandardCurveAxes,[0 max(standard_concentrations)])
            ylim(app.StandardCurveAxes,[0 round(max(standard_mean))])

            app.SlopeEditField.Value = line_fit.slope;
            app.InterceptEditField.Value = line_fit.intercept;
            app.rvalueEditField.Value = line_fit.r;


            app.plate_reader_data.standard_curve_fit = line_fit;
            app.plate_reader_data.standard_values = standard_values;
            app.plate_reader_data.standard_mean = standard_mean;
            app.plate_reader_data.analyzed_data = analyzed_data;


            CalculateSampleValues(app)


        end

        function CalculateSampleValues(app)

            col_names = app.plate_reader_data.col_names;
            plate_layout = app.plate_reader_data.plate_layout;
            formatted_data = app.plate_reader_data.formatted_data;
            analyzed_data = app.plate_reader_data.analyzed_data;
            dilution_factor = app.DilutionfactorEditField.Value;

            slope = app.plate_reader_data.standard_curve_fit.slope;
            intercept = app.plate_reader_data.standard_curve_fit.intercept;

            for i = 1 : numel(col_names)
                sample_mask(:,i) = strcmpi(plate_layout.(col_names{i}),'Sample');
            end

            sample_matrix = formatted_data(any(sample_mask,2),any(sample_mask,1));

            sample_values = dilution_factor*(sample_matrix - intercept)./slope;

            analyzed_data(any(sample_mask,2),any(sample_mask,1)) = sample_values;

            scatter(app.SampleValuesAxes,sample_values,sample_matrix,50,'MarkerFaceColor','b','MarkerEdgeColor','k','MarkerFaceAlpha',0.5)

            app.plate_reader_data.dilution_factor = dilution_factor;
            app.plate_reader_data.sample_values = sample_values;
            app.plate_reader_data.analyzed_data = analyzed_data;
        end

        function RefreshDisplay(app)

            figs = {'StandardCurveAxes','SampleValuesAxes'};
            for i = 1 : numel(figs)
                clf(App.(figs{i}))
            end

            txt = {'Slope','Intercept','rvalue'};

            for i = 1 : numel(txt)
                App.(txt{i}).Value = 0;
            end
        end

        function FormatData(app)

            initial_matrix = [];
            initial_matrix = readmatrix(app.plate_reader_data.data_file_string);
            app.plate_reader_data.temperature = initial_matrix(1,1);
            [m,n] = size(initial_matrix);
            shifted_matrix = NaN(m,n);

            shifted_matrix(1, :) = initial_matrix(1, :);
            shifted_matrix(2:end, 2:end) = initial_matrix(2:end, 1:end-1);

            shifted_matrix(:,1) = [];

            k = 1;
            size(shifted_matrix,1)
            for i = 1 : size(shifted_matrix,1)
                if sum(isnan(shifted_matrix(i,:))) == size(shifted_matrix,2)
                    rem_ix(k) = i;
                    k = k + 1;
                end
            end

            shifted_matrix(rem_ix,:) = []
            rem_ix = [];
            size(shifted_matrix,1)
            k = 1;
            for i = 1 : size(shifted_matrix,2)
                sum(isnan(shifted_matrix(:,i)))
                if sum(isnan(shifted_matrix(:,i))) == size(shifted_matrix,1)
                    rem_ix(k) = i;
                    k = k + 1;
                end
            end
            shifted_matrix(:,rem_ix) = [];

            col_names = app.plate_reader_data.plate_layout.Properties.VariableNames;
            col_names(1) = [];


            k = 1;
            for i = 1:numel(col_names)

                if any(~strcmpi(app.plate_reader_data.plate_layout.(col_names{i}),'Blank'))
                    formatted_data(:,i) = shifted_matrix(:,k);
                    k = k + 1;
                else
                    formatted_data(:,i) = NaN(size(shifted_matrix,1),1);
                end

            end

            app.plate_reader_data.formatted_data = formatted_data;
            app.plate_reader_data.col_names = col_names;
            app.CalculateStandardCurve


        end

        function ReloadDisplay(app)
            app.plate_reader_data
            line_fit = app.plate_reader_data.standard_curve_fit;
            standard_concentrations = app.plate_reader_data.standard_concentrations.concentration;
            standard_mean = app.plate_reader_data.standard_mean;

            hold(app.StandardCurveAxes,"on")
            plot(app.StandardCurveAxes,line_fit.x_fit, line_fit.y_fit, 'LineStyle','-', ...
                'LineWidth', 1.75,'Color',[0 0 0 0.7]);
            scatter(app.StandardCurveAxes,standard_concentrations,standard_mean,50,'MarkerFaceColor','g','MarkerEdgeColor','k','MarkerFaceAlpha',0.5)
            xlim(app.StandardCurveAxes,[0 max(standard_concentrations)])
            ylim(app.StandardCurveAxes,[0 round(max(standard_mean))])

            app.SlopeEditField.Value = line_fit.slope;
            app.InterceptEditField.Value = line_fit.intercept;
            app.rvalueEditField.Value = line_fit.r;

            col_names = app.plate_reader_data.col_names;
            plate_layout = app.plate_reader_data.plate_layout;
            formatted_data = app.plate_reader_data.formatted_data;
            sample_values = app.plate_reader_data.sample_values;

            for i = 1 : numel(col_names)
                sample_mask(:,i) = strcmpi(plate_layout.(col_names{i}),'Sample');
            end

            sample_matrix = formatted_data(any(sample_mask,2),any(sample_mask,1));

            scatter(app.SampleValuesAxes,sample_values,sample_matrix,50,'MarkerFaceColor','b','MarkerEdgeColor','k','MarkerFaceAlpha',0.5)


        end

        function ConvertData(app)
            assay = app.AssayTabs.SelectedTab.Title;
            layout_size = size(app.plate_reader_data.plate_layout);
            layout_size(2) = layout_size(2)-1;

            lines = [];
            lines = fileread(app.plate_reader_data.data_file_string);
            lines = regexp(lines, '\r\n|\n|\r', 'split')';
            lines = string(lines);

            read_until_ix = find(contains(lines, "~End"), 1);
            if isempty(read_until_ix)
                read_until_ix = numel(lines);
            else
                read_until_ix = read_until_ix - 1;
            end

            lines = lines(1:read_until_ix);

            read_after_ix = find(contains(lines, "Temperature"), 1);

            lines = lines(read_after_ix+1:end);
            lines = lines(strlength(strtrim(lines)) > 0);
            parts = split(lines, sprintf('\t'));

            switch assay
                case 'Bradford'
                initial_matrix = double(parts);
                initial_matrix(:,1) = [];
                app.plate_reader_data.temperature = initial_matrix(1,1);
                initial_matrix(:,1) = [];

                mat_size = size(initial_matrix);

                if mat_size(2) ~= layout_size(2)
                    initial_matrix(:,layout_size(2)+1:end) = [];
                end

                case 'Citrate Synthase'
                    time_column = parts(:,1);
                    d_parts = double(parts);
                    d_parts(:,1) = [];
                    app.plate_reader_data.temperature = d_parts(1,1);
                    d_parts(:,1) = [];
                    mat_size = size(d_parts);
                    if mat_size(2) ~= layout_size(2)
                        d_parts(:,layout_size(2)+1:end) = [];
                    end

                    time_points = sum(~strcmp(time_column,""));

                    initial_matrix = nan(layout_size(1),layout_size(2),time_points);

                    for i = 1 : time_points
                        initial_matrix(:,:,i) = d_parts(1+(i-1)*8:i*8,:);
                    end

            end

            col_names = app.plate_reader_data.plate_layout.Properties.VariableNames;
            col_names(1) = [];
            app.plate_reader_data.formatted_data = initial_matrix;
            app.plate_reader_data.col_names = col_names;
            app.CalculateStandardCurve


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('utilities'))
            movegui(app.PlateReaderUIFigure,'center')
        end

        % Menu selected function: LoadlayoutMenu
        function LoadlayoutMenuSelected(app, event)
            app.PlateReaderUIFigure.Visible = 'off';
            [file_string,path_string]=uigetfile2( ...
                {'*.xlsx','XLSX'}, ...
                'Select Layout File');
            app.PlateReaderUIFigure.Visible = 'on';
            figure(app.PlateReaderUIFigure)

            if (path_string~=0)
                app.plate_reader_data = [];
                app.plate_reader_data.layout_file_string = fullfile(path_string,file_string);
                app.plate_reader_data.sample_layout = readtable(app.plate_reader_data.layout_file_string,'Sheet','sample_layout');
                app.plate_reader_data.plate_layout = readtable(app.plate_reader_data.layout_file_string,'Sheet','plate_layout');
                app.plate_reader_data.standard_concentrations = readtable(app.plate_reader_data.layout_file_string,'Sheet','standard_concentrations');
                app.LoaddataMenu.Enable = 1;
                app.ExportanalysisMenu.Enable = 1;
            end

        end

        % Menu selected function: LoaddataMenu
        function LoaddataMenuSelected(app, event)
            app.PlateReaderUIFigure.Visible = 'off';

            [file_string,path_string]=uigetfile2( ...
                {'*.txt','TXT'}, ...
                'Select Data File');
            app.PlateReaderUIFigure.Visible = 'on';
            figure(app.PlateReaderUIFigure)

            if (path_string~=0)
                app.plate_reader_data.data_file_string = fullfile(path_string,file_string);
                % app.FormatData;
                app.ConvertData;
            end
        end

        % Menu selected function: ExportanalysisMenu
        function ExportanalysisMenuSelected(app, event)
            app.PlateReaderUIFigure.Visible = 'off';
            [file_string,path_string] = uiputfile2( ...
                {'*.xlsx','Excel file'},'Enter Excel File Name For Analysis Results');
            app.PlateReaderUIFigure.Visible = 'on';
            figure(app.PlateReaderUIFigure)

            if (path_string~=0)
                output_file_string = fullfile(path_string,file_string);

                try
                    delete(output_file_string);
                end


                result_layout = app.plate_reader_data.sample_layout;
                plate_layout = app.plate_reader_data.plate_layout;
                analyzed_data = app.plate_reader_data.analyzed_data;
                standard_values = app.plate_reader_data.standard_values;

                for i = 1 : numel(result_layout.well_no)

                    well_info = regexp(result_layout.well_no(i), '(\d+|[a-zA-Z]+)', 'match');
                    well_info = well_info{1};
                    well_row = well_info{1};
                    well_column = str2double(well_info{2});
                    well_row_ix = find(strcmp(plate_layout.Row,well_row));
                    result_layout.concentration_mg_per_ml(i) = analyzed_data(well_row_ix,well_column);
                    result_layout.volume_for_cs_assay_ul(i) = result_layout.concentration(i)*30;
                end

                un_hashcode = unique(result_layout.hashcode);
                un_specimen = unique(result_layout.specimen_no);

                var_names = result_layout.Properties.VariableNames;
                var_types = result_layout.Properties.VariableTypes;

                summary_table = table('Size', [0, numel(var_names)], 'VariableNames', var_names, 'VariableTypes', var_types);
                summary_table = removevars(summary_table,'well_no');
                new_var_names = summary_table.Properties.VariableNames;
                new_var_names(strcmp(new_var_names,'concentration')) = [];

                m = 1;
                for i = 1 : numel(un_hashcode)
                    for j = 1 : numel(un_specimen)
                        h_ix = find(strcmp(result_layout.hashcode,un_hashcode{i}));
                        s_ix = find(strcmp(result_layout(h_ix,:).specimen_no,un_specimen{j}));
                        for u = 1 : numel(new_var_names)
                            summary_table.(new_var_names{u})(m) = result_layout.(new_var_names{u})(s_ix(1));
                        end
                        summary_table.concentration(m) = mean(result_layout.concentration(s_ix));
                        m = m + 1;
                    end
                end
            end

            standard_table = table;

            for i = 1 : size(standard_values,2)
                col_name = sprintf('standard_col_%i',i);
                standard_table.(col_name) = standard_values(:,i);
            end

            writetable(summary_table,output_file_string,'Sheet','analysis_summary')
            writetable(result_layout,output_file_string,'Sheet','plate_results')
            writetable(standard_table,output_file_string,'Sheet','standard_columns')

            output_file_string = replace(output_file_string,'.xlsx','.pr');
            analysis_session = app.plate_reader_data;
            save(output_file_string,'analysis_session')


        end

        % Menu selected function: LoadanalysisMenu
        function LoadanalysisMenuSelected(app, event)
            app.PlateReaderUIFigure.Visible = 'off';

            [file_string,path_string]=uigetfile2( ...
                {'*.pr','PR'}, ...
                'Select PlateReader File');
            app.PlateReaderUIFigure.Visible = 'on';
            figure(app.PlateReaderUIFigure)
            if (path_string~=0)
                temp = load(fullfile(path_string,file_string),'-mat','analysis_session');
                analysis_session = temp.analysis_session;
                app.plate_reader_data = [];
                app.plate_reader_data = analysis_session;
                ReloadDisplay(app);
            end

        end

        % Value changed function: DilutionfactorEditField
        function DilutionfactorEditFieldValueChanged(app, event)
            CalculateSampleValues(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlateReaderUIFigure = uifigure('Visible', 'off');
            app.PlateReaderUIFigure.Name = 'PlateReader';
            app.Menu = uimenu(app.PlateReaderUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadlayoutMenu = uimenu(app.Menu);
            app.LoadlayoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadlayoutMenuSelected, true);
            app.LoadlayoutMenu.Text = 'Load layout';
            app.LoaddataMenu = uimenu(app.Menu);
            app.LoaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddataMenuSelected, true);
            app.LoaddataMenu.Enable = 'off';
            app.LoaddataMenu.Text = 'Load data';
            app.ExportanalysisMenu = uimenu(app.Menu);
            app.ExportanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportanalysisMenuSelected, true);
            app.ExportanalysisMenu.Enable = 'off';
            app.ExportanalysisMenu.Text = 'Export analysis';
            app.LoadanalysisMenu = uimenu(app.Menu);
            app.LoadanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadanalysisMenuSelected, true);
            app.LoadanalysisMenu.Text = 'Load analysis';
            app.AssayTabs = uitabgroup(app.PlateReaderUIFigure);
            app.BradfordTab = uitab(app.AssayTabs);
            app.BradfordTab.Title = 'Bradford';
            app.StandardCurvePanel = uipanel(app.BradfordTab);
            app.StandardCurvePanel.Title = 'Standard Curve';
            app.StandardCurveAxes = uiaxes(app.StandardCurvePanel);
            xlabel(app.StandardCurveAxes, 'Concentration (ug/ml)')
            ylabel(app.StandardCurveAxes, 'Absorbance')
            zlabel(app.StandardCurveAxes, 'Z')
            app.SlopeEditFieldLabel = uilabel(app.StandardCurvePanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.StandardCurvePanel, 'numeric');
            app.SlopeEditField.Editable = 'off';
            app.rvalueEditFieldLabel = uilabel(app.StandardCurvePanel);
            app.rvalueEditFieldLabel.Text = 'r-value';
            app.rvalueEditField = uieditfield(app.StandardCurvePanel, 'numeric');
            app.rvalueEditField.Editable = 'off';
            app.InterceptEditFieldLabel = uilabel(app.StandardCurvePanel);
            app.InterceptEditFieldLabel.Text = 'Intercept';
            app.InterceptEditField = uieditfield(app.StandardCurvePanel, 'numeric');
            app.InterceptEditField.Editable = 'off';
            app.SampleValuesPanel = uipanel(app.BradfordTab);
            app.SampleValuesPanel.Title = 'Sample Values';
            app.SampleValuesAxes = uiaxes(app.SampleValuesPanel);
            xlabel(app.SampleValuesAxes, 'Concentration (ug/ml)')
            ylabel(app.SampleValuesAxes, 'Absorbance')
            zlabel(app.SampleValuesAxes, 'Z')
            app.DilutionfactorEditFieldLabel = uilabel(app.SampleValuesPanel);
            app.DilutionfactorEditFieldLabel.Text = 'Dilution factor';
            app.DilutionfactorEditField = uieditfield(app.SampleValuesPanel, 'numeric');
            app.DilutionfactorEditField.ValueChangedFcn = createCallbackFcn(app, @DilutionfactorEditFieldValueChanged, true);
            app.DilutionfactorEditField.Value = 1;
            app.CitrateSynthaseTab = uitab(app.AssayTabs);
            app.CitrateSynthaseTab.Title = 'Citrate Synthase';
            app.StandardCurvePanel_2 = uipanel(app.CitrateSynthaseTab);
            app.StandardCurvePanel_2.Title = 'Standard Curve';
            app.StandardCurveAxes_2 = uiaxes(app.StandardCurvePanel_2);
            xlabel(app.StandardCurveAxes_2, 'Concentration (ug/ml)')
            ylabel(app.StandardCurveAxes_2, 'Absorbance')
            zlabel(app.StandardCurveAxes_2, 'Z')
            app.SlopeEditField_2Label = uilabel(app.StandardCurvePanel_2);
            app.SlopeEditField_2Label.Text = 'Slope';
            app.SlopeEditField_2 = uieditfield(app.StandardCurvePanel_2, 'numeric');
            app.SlopeEditField_2.Editable = 'off';
            app.rvalueEditField_2Label = uilabel(app.StandardCurvePanel_2);
            app.rvalueEditField_2Label.Text = 'r-value';
            app.rvalueEditField_2 = uieditfield(app.StandardCurvePanel_2, 'numeric');
            app.rvalueEditField_2.Editable = 'off';
            app.InterceptEditField_2Label = uilabel(app.StandardCurvePanel_2);
            app.InterceptEditField_2Label.Text = 'Intercept';
            app.InterceptEditField_2 = uieditfield(app.StandardCurvePanel_2, 'numeric');
            app.InterceptEditField_2.Editable = 'off';
            app.SampleValuesPanel_2 = uipanel(app.CitrateSynthaseTab);
            app.SampleValuesPanel_2.Title = 'Sample Values';
            app.SampleValuesAxes_2 = uiaxes(app.SampleValuesPanel_2);
            xlabel(app.SampleValuesAxes_2, 'Concentration (ug/ml)')
            ylabel(app.SampleValuesAxes_2, 'Absorbance')
            zlabel(app.SampleValuesAxes_2, 'Z')
            app.DilutionfactorEditField_2Label = uilabel(app.SampleValuesPanel_2);
            app.DilutionfactorEditField_2Label.Text = 'Dilution factor';
            app.DilutionfactorEditField_2 = uieditfield(app.SampleValuesPanel_2, 'numeric');
            app.DilutionfactorEditField_2.Value = 1;
            % Show the figure after all components are created
            app.PlateReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateReader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlateReaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlateReaderUIFigure)
        end
    end
end